classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        OperationModeButtonGroup    matlab.ui.container.ButtonGroup
        SumOfSinesButton            matlab.ui.control.ToggleButton
        RandomPulseButton           matlab.ui.control.ToggleButton
        PauseButton                 matlab.ui.control.ToggleButton
        ReferenceButton             matlab.ui.control.ToggleButton
        SeriesButton                matlab.ui.control.ToggleButton
        PulseButton                 matlab.ui.control.ToggleButton
        SquareButton                matlab.ui.control.ToggleButton
        CenterPanel                 matlab.ui.container.Panel
        SeriesSelectorButtonGroup   matlab.ui.container.ButtonGroup
        CustomButton                matlab.ui.control.ToggleButton
        SawtoothButton              matlab.ui.control.ToggleButton
        TriangleButton              matlab.ui.control.ToggleButton
        RandomPulsesEditField       matlab.ui.control.NumericEditField
        RandomPulsesEditFieldLabel  matlab.ui.control.Label
        PulsesEditField             matlab.ui.control.NumericEditField
        PulsesEditFieldLabel        matlab.ui.control.Label
        FreqEditField               matlab.ui.control.NumericEditField
        FreqEditFieldLabel          matlab.ui.control.Label
        HighCurrentEditField        matlab.ui.control.NumericEditField
        HighCurrentEditFieldLabel   matlab.ui.control.Label
        LowCurrentEditField         matlab.ui.control.NumericEditField
        LowCurrentEditFieldLabel    matlab.ui.control.Label
        ReferencemASlider           matlab.ui.control.Slider
        ReferencemASliderLabel      matlab.ui.control.Label
        mALabel_2                   matlab.ui.control.Label
        HzLabel                     matlab.ui.control.Label
        mALabel                     matlab.ui.control.Label
        SquareWaveValuesLabel       matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        ResetButton                 matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = private)
        s; % Serial Port Object
        ref = '0'; refOld = '0'; % Reference Values
        reset = 1; % Reset Boolean
        RH; RL; Freq;
        current = 0;
        pulses; randPulses;
        series;
        seriesString;

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.s = serialport("COM3", 115200);
            app.OperationModeButtonGroup.SelectedObject = app.PauseButton;
            disp(readline(app.s));
            app.PulsesEditField.Value = 1;
            app.RandomPulsesEditField.Value = 1;

        end

        % Value changing function: ReferencemASlider
        function ReferencemASliderValueChanging(app, event)
            changingValue = event.Value;
            app.ref = num2str(changingValue);
        end

        % Selection changed function: OperationModeButtonGroup
        function OperationModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.OperationModeButtonGroup.SelectedObject;

            if (selectedButton == app.ReferenceButton)
                fID = fopen('data.txt', 'w');
                app.reset = 0;
                writeline(app.s, "reference");
                
                while (app.reset == 0)

                    if (~strcmp(app.refOld, app.ref))
                        writeline(app.s, app.ref);
                        app.refOld = app.ref;
                    end
                    app.current = char(readline(app.s));
                    fprintf(fID, app.current);
                    disp(app.current);
                    disp(app.ref);

                end

                writeline(app.s, "1000");              
                charArr = fileread('data.txt');
                refData = str2num(char(regexp(charArr,'[+-]?\d+\.?\d*','match')));
                save('refData.mat', 'refData');
                fclose(fID);
                
            end

            if (selectedButton == app.SquareButton)
                fID = fopen('data.txt', 'w');
                app.reset = 0;
                writeline(app.s, "square");
                app.RH = num2str(app.HighCurrentEditField.Value);
                app.RL = num2str(app.LowCurrentEditField.Value);
                app.Freq = num2str(app.FreqEditField.Value);
                writeline(app.s, app.RL);
                readline(app.s);
                writeline(app.s, app.RH);
                readline(app.s);
                writeline(app.s, app.Freq);
                readline(app.s);

                while (app.reset == 0)
                    app.current = char(readline(app.s));
                    disp(app.current);
                    fprintf(fID, app.current);
                end

                writeline(app.s, "end");
                disp(readline(app.s));

                charArr = fileread('data.txt');
                squareData = str2num(char(regexp(charArr,'[+-]?\d+\.?\d*','match')));
                save('squareData.mat', 'squareData');
                fclose(fID);

            end

            if (selectedButton == app.PulseButton)
                fID = fopen('data.txt', 'w');
                app.reset = 0;
                writeline(app.s, "pulse");
                disp(readline(app.s));
                app.pulses = app.PulsesEditField.Value;
                writeline(app.s, num2str(app.pulses));
                for i = 1:app.pulses
                    disp(readline(app.s));
                    p1 = strcat("Enter Amplitude ", num2str(i));
                    p1 = strcat(p1, " (mA)");
                    amp = char(inputdlg(p1));
                    writeline(app.s, amp);
                    disp(readline(app.s));
                    p2 = strcat("Enter Time ", num2str(i));
                    p2 = strcat(p2, " (ms)");
                    time = char(inputdlg(p2));
                    writeline(app.s, time);

                end
                while (app.reset == 0)
                    app.current = char(readline(app.s));
                    fprintf(fID, app.current);
                    disp(app.current);
                end

                writeline(app.s, "end");
                disp(readline(app.s));
                charArr = fileread('data.txt');
                pulseData = str2num(char(regexp(charArr,'[+-]?\d+\.?\d*','match')));
                save('pulseData.mat', 'pulseData');
                fclose(fID);
            end

            if (selectedButton == app.RandomPulseButton)
                fID = fopen('data.txt', 'w');
                app.reset = 0;
                writeline(app.s, "randPulse");
                disp(readline(app.s));
                app.randPulses = app.RandomPulsesEditField.Value;
                writeline(app.s, num2str(app.randPulses));
                for i = 1:app.randPulses
                    disp(readline(app.s));
                    p1 = strcat("Enter Amplitude ", num2str(i));
                    p1 = strcat(p1, " (mA)");
                    amp = char(inputdlg(p1));
                    writeline(app.s, amp);

                end
                writeline(app.s, '0');
                while (app.reset == 0)
                    app.current = char(readline(app.s));
                    fprintf(fID, app.current);
                    disp(app.current);
                end

                writeline(app.s, "end");
                disp(readline(app.s));

                charArr = fileread('data.txt');
                randomPulseData = str2num(char(regexp(charArr,'[+-]?\d+\.?\d*','match')));
                save('randomPulseData.mat', 'randomPulseData');
                fclose(fID);

            end

            if (selectedButton == app.SeriesButton)
                fID = fopen('data.txt', 'w');
                app.reset = 0;
                writeline(app.s, "series");
                disp(readline(app.s));
                if (app.SeriesSelectorButtonGroup.SelectedObject == app.TriangleButton)
                    app.series = load('triangle.mat');
                    app.series = app.series.triangle;
                    app.series = num2str(app.series);
                    writeline(app.s, num2str(length(app.series)));
                    for i = 1:length(app.series)
                        writeline(app.s, app.series(i,:));
                    end

                elseif(app.SeriesSelectorButtonGroup.SelectedObject == app.SawtoothButton)
                    app.series = load('saw.mat');
                    app.series = app.series.saw;
                    app.series = num2str(app.series);
                    writeline(app.s, num2str(length(app.series)));
                    for i = 1:length(app.series)
                        writeline(app.s, app.series(i,:));
                    end

                elseif(app.SeriesSelectorButtonGroup.SelectedObject == app.CustomButton)
                    loader = char(inputdlg("Load Series File:"));
                    app.series = load(loader);
                    app.series = app.series.custom;
                    app.series = num2str(app.series);
                    writeline(app.s, num2str(length(app.series)));
                    for i = 1:length(app.series)
                        writeline(app.s, app.series(i,:));
                    end


                end


                while (app.reset == 0)
                    app.current = char(readline(app.s));
                    fprintf(fID, app.current);
                    disp(app.current);
                end

                writeline(app.s, "end");
                disp(readline(app.s));
                charArr = fileread('data.txt');
                seriesData = str2num(char(regexp(charArr,'[+-]?\d+\.?\d*','match')));
                save('seriesData.mat', 'seriesData');
                fclose(fID);

            end

            if (selectedButton == app.SumOfSinesButton)
                fID = fopen('data.txt', 'w');
                app.reset = 0;
                writeline(app.s, "sumOfSines2");

                % Weight Data is Packaged as Index + Value in uint16
                w1 = 0x00000080;               % Index is Bitshifted Left 8 Bits
                f1 = bitshift(uint32(47), 8);              % 8 Bit Weight Value OR'ed with Index
                w2 = bitshift(0x00000080, 16);
                f2 = bitshift(uint32(85), 24);
                send = bitor(w1, f1);
                send = bitor(send,w2);
                send = bitor(send,f2);
                writeline(app.s, num2str(send));
                pause(1);

                while (app.reset == 0)
                    app.current = char(readline(app.s));
                    fprintf(fID, app.current);
                    disp(app.current);
                end

                writeline(app.s, "end");
                pause(1);
                disp(readline(app.s));

                charArr = fileread('data.txt');
                sumOfSinesData = str2num(char(regexp(charArr,'[+-]?\d+\.?\d*','match')));
                save('sumOfSinesData.mat', 'sumOfSinesData');
                fclose(fID);

            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.reset = 1;
            app.ref = '0';
            app.refOld = '0';
            app.OperationModeButtonGroup.SelectedObject = app.PauseButton;
            app.ReferencemASlider.Value = 0;
            app.current = readline(app.s);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 157};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.OperationModeButtonGroup = uibuttongroup(app.LeftPanel);
            app.OperationModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OperationModeButtonGroupSelectionChanged, true);
            app.OperationModeButtonGroup.Title = 'Operation Mode';
            app.SquareButton = uitogglebutton(app.OperationModeButtonGroup);
            app.SquareButton.Text = 'Square';
            app.SquareButton.Value = true;
            app.PulseButton = uitogglebutton(app.OperationModeButtonGroup);
            app.PulseButton.Text = 'Pulse';
            app.SeriesButton = uitogglebutton(app.OperationModeButtonGroup);
            app.SeriesButton.Text = 'Series';
            app.ReferenceButton = uitogglebutton(app.OperationModeButtonGroup);
            app.ReferenceButton.Text = 'Reference';
            app.PauseButton = uitogglebutton(app.OperationModeButtonGroup);
            app.PauseButton.Text = 'Pause';
            app.RandomPulseButton = uitogglebutton(app.OperationModeButtonGroup);
            app.RandomPulseButton.Text = 'Random Pulse';
            app.SumOfSinesButton = uitogglebutton(app.OperationModeButtonGroup);
            app.SumOfSinesButton.Text = 'Sum Of Sines';
            app.CenterPanel = uipanel(app.GridLayout);
            app.SquareWaveValuesLabel = uilabel(app.CenterPanel);
            app.SquareWaveValuesLabel.Text = 'Square Wave Values';
            app.mALabel = uilabel(app.CenterPanel);
            app.mALabel.Text = '(mA)';
            app.HzLabel = uilabel(app.CenterPanel);
            app.HzLabel.Text = '(Hz)';
            app.mALabel_2 = uilabel(app.CenterPanel);
            app.mALabel_2.Text = '(mA)';
            app.ReferencemASliderLabel = uilabel(app.CenterPanel);
            app.ReferencemASliderLabel.Text = 'Reference (mA)';
            app.ReferencemASlider = uislider(app.CenterPanel);
            app.ReferencemASlider.Limits = [-2 2];
            app.ReferencemASlider.MajorTicks = [-2 -1 0 1 2];
            app.ReferencemASlider.ValueChangingFcn = createCallbackFcn(app, @ReferencemASliderValueChanging, true);
            app.LowCurrentEditFieldLabel = uilabel(app.CenterPanel);
            app.LowCurrentEditFieldLabel.Text = 'Low Current';
            app.LowCurrentEditField = uieditfield(app.CenterPanel, 'numeric');
            app.LowCurrentEditField.Limits = [-2 2];
            app.HighCurrentEditFieldLabel = uilabel(app.CenterPanel);
            app.HighCurrentEditFieldLabel.Text = 'High Current';
            app.HighCurrentEditField = uieditfield(app.CenterPanel, 'numeric');
            app.HighCurrentEditField.Limits = [-2 2];
            app.FreqEditFieldLabel = uilabel(app.CenterPanel);
            app.FreqEditFieldLabel.Text = 'Freq';
            app.FreqEditField = uieditfield(app.CenterPanel, 'numeric');
            app.FreqEditField.Limits = [0 650];
            app.FreqEditField.Value = 1;
            app.PulsesEditFieldLabel = uilabel(app.CenterPanel);
            app.PulsesEditFieldLabel.Text = '# Pulses';
            app.PulsesEditField = uieditfield(app.CenterPanel, 'numeric');
            app.PulsesEditField.Limits = [0 100];
            app.RandomPulsesEditFieldLabel = uilabel(app.CenterPanel);
            app.RandomPulsesEditFieldLabel.Text = '# Random Pulses';
            app.RandomPulsesEditField = uieditfield(app.CenterPanel, 'numeric');
            app.RandomPulsesEditField.Limits = [0 100];
            app.SeriesSelectorButtonGroup = uibuttongroup(app.CenterPanel);
            app.SeriesSelectorButtonGroup.Title = 'Series Selector';
            app.TriangleButton = uitogglebutton(app.SeriesSelectorButtonGroup);
            app.TriangleButton.Text = 'Triangle';
            app.TriangleButton.Value = true;
            app.SawtoothButton = uitogglebutton(app.SeriesSelectorButtonGroup);
            app.SawtoothButton.Text = 'Sawtooth';
            app.CustomButton = uitogglebutton(app.SeriesSelectorButtonGroup);
            app.CustomButton.Text = 'Custom';
            app.RightPanel = uipanel(app.GridLayout);
            app.ResetButton = uibutton(app.RightPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end